classdef RoBaristaGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DoBotControlsTab               matlab.ui.container.Tab
        DoBotTeachPanel                matlab.ui.container.Panel
        q5Slider                       matlab.ui.control.Slider
        q5SliderLabel                  matlab.ui.control.Label
        q4Slider                       matlab.ui.control.Slider
        q4SliderLabel                  matlab.ui.control.Label
        q3Slider                       matlab.ui.control.Slider
        q3SliderLabel                  matlab.ui.control.Label
        q2Slider                       matlab.ui.control.Slider
        q2SliderLabel                  matlab.ui.control.Label
        q1Slider                       matlab.ui.control.Slider
        q1SliderLabel                  matlab.ui.control.Label
        DoBotEndEffectorPositionPanel  matlab.ui.container.Panel
        MoveEndEffectorButton          matlab.ui.control.Button
        zEditField                     matlab.ui.control.NumericEditField
        zEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        KukaControlsTab                matlab.ui.container.Tab
        KukaTeachPanel                 matlab.ui.container.Panel
        q7Slider                       matlab.ui.control.Slider
        q7SliderLabel                  matlab.ui.control.Label
        q6Slider                       matlab.ui.control.Slider
        q6SliderLabel                  matlab.ui.control.Label
        q5Slider_2                     matlab.ui.control.Slider
        q5Slider_2Label                matlab.ui.control.Label
        q4Slider_2                     matlab.ui.control.Slider
        q4Slider_2Label                matlab.ui.control.Label
        q3Slider_2                     matlab.ui.control.Slider
        q3Slider_2Label                matlab.ui.control.Label
        q2Slider_2                     matlab.ui.control.Slider
        q2Slider_2Label                matlab.ui.control.Label
        q1Slider_2                     matlab.ui.control.Slider
        q1Slider_2Label                matlab.ui.control.Label
        KukaEndEffectorPositionPanel   matlab.ui.container.Panel
        MoveEndEffectorButton_2        matlab.ui.control.Button
        zEditField_2                   matlab.ui.control.NumericEditField
        zEditField_2Label              matlab.ui.control.Label
        yEditField_2                   matlab.ui.control.NumericEditField
        yEditField_2Label              matlab.ui.control.Label
        xEditField_2                   matlab.ui.control.NumericEditField
        xEditField_2Label              matlab.ui.control.Label
        eStopButton                    matlab.ui.control.StateButton
        eStopLamp                      matlab.ui.control.Lamp
        processLog                     matlab.ui.control.EditField
        startProgram                   matlab.ui.control.Button
        loadEnv                        matlab.ui.control.Button
        axes1                          matlab.ui.control.UIAxes
    end


    methods (Access = private)
        function slider1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
                        
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end

        function slider2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to slider2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end

        function slider3_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to slider3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end

        function slider4_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to slider4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function RoBaristaGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to RoBaristaGUI (see VARARGIN)

            % Choose default command line output for RoBaristaGUI
            handles.output = hObject;
            
            
            %default values            
            set(app.startProgram, 'Enable', 'Off');
            set(app.eStopButton, 'Enable', 'Off');
            set(app.eStopLamp, 'Enable', 'Off');
            set(app.DoBotEndEffectorPositionPanel, 'Enable', 'Off');
            set(app.DoBotTeachPanel, 'Enable', 'Off');
            set(app.KukaEndEffectorPositionPanel, 'Enable', 'Off');
            set(app.KukaTeachPanel, 'Enable', 'Off');
            

            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: loadEnv
        function loadEnvButtonPushed(app, event)
            % Spawn Environment with robots
            app.processLog.Value = "Loading Environment...";
            
            environment.spawnEnvironment()
            app.processLog.Value = "Café Ready";
            
            set(app.loadEnv, 'Enable', 'Off');
            set(app.startProgram, 'Enable', 'On');
            set(app.eStopButton, 'Enable', 'On');
            set(app.eStopLamp, 'Enable', 'On');
            set(app.DoBotEndEffectorPositionPanel, 'Enable', 'On');
            set(app.DoBotTeachPanel, 'Enable', 'On');
            set(app.KukaEndEffectorPositionPanel, 'Enable', 'On');
            set(app.KukaTeachPanel, 'Enable', 'On');
        end

        % Value changed function: eStopButton
        function eStopButtonValueChanged(app, event)
            value = app.eStopButton.Value;
            if value == 1
                app.eStopLamp.Color = "red";
                app.processLog.Value = "E-STOP ACTIVE";
                
                % Disable all components
                set(app.startProgram, 'Enable', 'Off');
                set(app.eStopLamp, 'Enable', 'Off');
                set(app.DoBotEndEffectorPositionPanel, 'Enable', 'Off');
                set(app.DoBotTeachPanel, 'Enable', 'Off');
                set(app.KukaEndEffectorPositionPanel, 'Enable', 'Off');
                set(app.KukaTeachPanel, 'Enable', 'Off');
            end
            if value == 0
                app.eStopLamp.Color = "green";
                app.processLog.Value = "System reset in progress...";
               
                %when system has reset, enable all components:
                pause(3); %change this to the function that will cancel the robot lol
                set(app.startProgram, 'Enable', 'On');
                set(app.eStopButton, 'Enable', 'On');
                set(app.eStopLamp, 'Enable', 'On');
                set(app.DoBotEndEffectorPositionPanel, 'Enable', 'On');
                set(app.DoBotTeachPanel, 'Enable', 'On');
                set(app.KukaEndEffectorPositionPanel, 'Enable', 'On');
                set(app.KukaTeachPanel, 'Enable', 'On');
                
                app.processLog.Value = "Café Ready";
            end
        end

        % Button pushed function: startProgram
        function startProgramButtonPushed(app, event)
           if app.eStopLamp.Color == [0 1 0]
               app.processLog.Value = "RoBarista in Action";
               
           else
               app.processLog.Value = "E-STOP ACTIVE";
           end
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            value = app.q1Slider.Value;
            app.processLog.Value = "Updating...";
            environment.DobotSlider(1, value);
            app.processLog.Value = "Café Ready";
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            value = app.q2Slider.Value;
            app.processLog.Value = "Updating...";
            environment.DobotSlider(2, value);
            app.processLog.Value = "Café Ready";
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            value = app.q3Slider.Value;
            app.processLog.Value = "Updating...";
            environment.DobotSlider(3, value);
            app.processLog.Value = "Café Ready";
        end

        % Value changed function: q4Slider
        function q4SliderValueChanged(app, event)
            value = app.q4Slider.Value;
            app.processLog.Value = "Updating...";
            environment.DobotSlider(4, value);
            app.processLog.Value = "Café Ready";
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)
            value = app.q5Slider.Value;
            app.processLog.Value = "Updating...";
            environment.DobotSlider(5, value);
            app.processLog.Value = "Café Ready";
        end

        % Value changed function: q1Slider_2
        function q1Slider_2ValueChanged(app, event)
            value = app.q1Slider_2.Value;
            app.processLog.Value = "Updating...";
            environment.KukaSlider(1, value);
            app.processLog.Value = "Café Ready";
        end

        % Value changed function: q2Slider_2
        function q2Slider_2ValueChanged(app, event)
            value = app.q2Slider_2.Value;
            app.processLog.Value = "Updating...";
            environment.KukaSlider(2, value);
            app.processLog.Value = "Café Ready";
        end

        % Value changed function: q3Slider_2
        function q3Slider_2ValueChanged(app, event)
            value = app.q3Slider_2.Value;
            app.processLog.Value = "Updating...";
            environment.KukaSlider(3, value);
            app.processLog.Value = "Café Ready";
        end

        % Value changed function: q4Slider_2
        function q4Slider_2ValueChanged(app, event)
            value = app.q4Slider_2.Value;
            app.processLog.Value = "Updating...";
            environment.KukaSlider(4, value);
            app.processLog.Value = "Café Ready";
        end

        % Value changed function: q5Slider_2
        function q5Slider_2ValueChanged(app, event)
            value = app.q5Slider_2.Value;
            app.processLog.Value = "Updating...";
            environment.KukaSlider(5, value);
            app.processLog.Value = "Café Ready";
        end

        % Value changed function: q6Slider
        function q6SliderValueChanged(app, event)
            value = app.q6Slider.Value;
            app.processLog.Value = "Updating...";
            environment.KukaSlider(6, value);
            app.processLog.Value = "Café Ready";
        end

        % Value changed function: q7Slider
        function q7SliderValueChanged(app, event)
            value = app.q7Slider.Value;
            app.processLog.Value = "Updating...";
            environment.KukaSlider(7, value);
            app.processLog.Value = "Café Ready";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'RoBaristaGUI';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.loadEnv = uibutton(app.figure1, 'push');
            app.loadEnv.ButtonPushedFcn = createCallbackFcn(app, @loadEnvButtonPushed, true);
            app.loadEnv.Tag = 'loadEnv';
            app.loadEnv.Text = 'Load Environment';
            app.startProgram = uibutton(app.figure1, 'push');
            app.startProgram.ButtonPushedFcn = createCallbackFcn(app, @startProgramButtonPushed, true);
            app.startProgram.Tag = 'startProgram';
            app.startProgram.Text = 'Start Program';
            app.processLog = uieditfield(app.figure1, 'text');
            app.eStopLamp = uilamp(app.figure1);
            app.eStopLamp.Tag = 'eStopLamp';
            app.eStopButton = uibutton(app.figure1, 'state');
            app.eStopButton.ValueChangedFcn = createCallbackFcn(app, @eStopButtonValueChanged, true);
            app.eStopButton.Tag = 'eStopButton';
            app.eStopButton.Icon = 'eStop.png';
            app.eStopButton.Text = '';
            app.TabGroup = uitabgroup(app.figure1);
            app.DoBotControlsTab = uitab(app.TabGroup);
            app.DoBotControlsTab.Title = 'DoBot Controls';
            app.DoBotControlsTab.Tag = 'controlPanel';
            app.DoBotEndEffectorPositionPanel = uipanel(app.DoBotControlsTab);
            app.DoBotEndEffectorPositionPanel.Title = 'End Effector Position';
            app.xEditFieldLabel = uilabel(app.DoBotEndEffectorPositionPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.DoBotEndEffectorPositionPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.DoBotEndEffectorPositionPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.DoBotEndEffectorPositionPanel, 'numeric');
            app.zEditFieldLabel = uilabel(app.DoBotEndEffectorPositionPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.DoBotEndEffectorPositionPanel, 'numeric');
            app.MoveEndEffectorButton = uibutton(app.DoBotEndEffectorPositionPanel, 'push');
            app.MoveEndEffectorButton.Text = 'Move End Effector';
            app.DoBotTeachPanel = uipanel(app.DoBotControlsTab);
            app.DoBotTeachPanel.Title = 'Teach';
            app.q1SliderLabel = uilabel(app.DoBotTeachPanel);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.DoBotTeachPanel);
            app.q1Slider.Limits = [-180 180];
            app.q1Slider.MajorTicks = [];
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q1Slider.MinorTicks = [];
            app.q2SliderLabel = uilabel(app.DoBotTeachPanel);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.DoBotTeachPanel);
            app.q2Slider.Limits = [0 90];
            app.q2Slider.MajorTicks = [];
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q2Slider.MinorTicks = [];
            app.q3SliderLabel = uilabel(app.DoBotTeachPanel);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.DoBotTeachPanel);
            app.q3Slider.Limits = [0 90];
            app.q3Slider.MajorTicks = [];
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q3Slider.MinorTicks = [];
            app.q4SliderLabel = uilabel(app.DoBotTeachPanel);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.DoBotTeachPanel);
            app.q4Slider.MajorTicks = [];
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @q4SliderValueChanged, true);
            app.q4Slider.MinorTicks = [];
            app.q5SliderLabel = uilabel(app.DoBotTeachPanel);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.DoBotTeachPanel);
            app.q5Slider.Limits = [0 90];
            app.q5Slider.MajorTicks = [];
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.q5Slider.MinorTicks = [];
            app.KukaControlsTab = uitab(app.TabGroup);
            app.KukaControlsTab.Title = 'Kuka Controls';
            app.KukaEndEffectorPositionPanel = uipanel(app.KukaControlsTab);
            app.KukaEndEffectorPositionPanel.Title = 'End Effector Position';
            app.xEditField_2Label = uilabel(app.KukaEndEffectorPositionPanel);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.KukaEndEffectorPositionPanel, 'numeric');
            app.yEditField_2Label = uilabel(app.KukaEndEffectorPositionPanel);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.KukaEndEffectorPositionPanel, 'numeric');
            app.zEditField_2Label = uilabel(app.KukaEndEffectorPositionPanel);
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.KukaEndEffectorPositionPanel, 'numeric');
            app.MoveEndEffectorButton_2 = uibutton(app.KukaEndEffectorPositionPanel, 'push');
            app.MoveEndEffectorButton_2.Text = 'Move End Effector';
            app.KukaTeachPanel = uipanel(app.KukaControlsTab);
            app.KukaTeachPanel.Title = 'Teach';
            app.q1Slider_2Label = uilabel(app.KukaTeachPanel);
            app.q1Slider_2Label.Text = 'q1';
            app.q1Slider_2 = uislider(app.KukaTeachPanel);
            app.q1Slider_2.MajorTicks = [];
            app.q1Slider_2.ValueChangedFcn = createCallbackFcn(app, @q1Slider_2ValueChanged, true);
            app.q1Slider_2.MinorTicks = [];
            app.q2Slider_2Label = uilabel(app.KukaTeachPanel);
            app.q2Slider_2Label.Text = 'q2';
            app.q2Slider_2 = uislider(app.KukaTeachPanel);
            app.q2Slider_2.MajorTicks = [];
            app.q2Slider_2.ValueChangedFcn = createCallbackFcn(app, @q2Slider_2ValueChanged, true);
            app.q2Slider_2.MinorTicks = [];
            app.q3Slider_2Label = uilabel(app.KukaTeachPanel);
            app.q3Slider_2Label.Text = 'q3';
            app.q3Slider_2 = uislider(app.KukaTeachPanel);
            app.q3Slider_2.MajorTicks = [];
            app.q3Slider_2.ValueChangedFcn = createCallbackFcn(app, @q3Slider_2ValueChanged, true);
            app.q3Slider_2.MinorTicks = [];
            app.q4Slider_2Label = uilabel(app.KukaTeachPanel);
            app.q4Slider_2Label.Text = 'q4';
            app.q4Slider_2 = uislider(app.KukaTeachPanel);
            app.q4Slider_2.MajorTicks = [];
            app.q4Slider_2.ValueChangedFcn = createCallbackFcn(app, @q4Slider_2ValueChanged, true);
            app.q4Slider_2.MinorTicks = [];
            app.q5Slider_2Label = uilabel(app.KukaTeachPanel);
            app.q5Slider_2Label.Text = 'q5';
            app.q5Slider_2 = uislider(app.KukaTeachPanel);
            app.q5Slider_2.MajorTicks = [];
            app.q5Slider_2.ValueChangedFcn = createCallbackFcn(app, @q5Slider_2ValueChanged, true);
            app.q5Slider_2.MinorTicks = [];
            app.q6SliderLabel = uilabel(app.KukaTeachPanel);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.KukaTeachPanel);
            app.q6Slider.MajorTicks = [];
            app.q6Slider.ValueChangedFcn = createCallbackFcn(app, @q6SliderValueChanged, true);
            app.q6Slider.MinorTicks = [];
            app.q7SliderLabel = uilabel(app.KukaTeachPanel);
            app.q7SliderLabel.Text = 'q7';
            app.q7Slider = uislider(app.KukaTeachPanel);
            app.q7Slider.MajorTicks = [];
            app.q7Slider.ValueChangedFcn = createCallbackFcn(app, @q7SliderValueChanged, true);
            app.q7Slider.MinorTicks = [];
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoBaristaGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)RoBaristaGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end